classdef Preferences < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        PreferencesPanel       matlab.ui.container.Panel
        GridLayout29           matlab.ui.container.GridLayout
        TabGroup               matlab.ui.container.TabGroup
        GeneralTab             matlab.ui.container.Tab
        GridLayout28           matlab.ui.container.GridLayout
        Panel_2                matlab.ui.container.Panel
        GridLayout30           matlab.ui.container.GridLayout
        SaveButton             matlab.ui.control.Button
        Panel                  matlab.ui.container.Panel
        GridLayout27           matlab.ui.container.GridLayout
        Checkbox_InitialSetup  matlab.ui.control.CheckBox
        DropDown_Language      matlab.ui.control.DropDown
        LanguageDropDownLabel  matlab.ui.control.Label
        DropDown_Units         matlab.ui.control.DropDown
        UnitsDropDownLabel     matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            global unitsData
            app.DropDown_Units.Value = unitsData.SelectedSystem;

            if ~ispref('cifrib','language')
                addpref('cifrib','language','english')
            end
            lang = getpref('cifrib', 'language');
            switch lang
                case 'english'
                    app.DropDown_Language.Value = 'English';
                case 'german'
                    app.DropDown_Language.Value = 'Deutsch';
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            global unitsData
            %apply Units

            if ~ispref('cifrib','units')
                addpref('cifrib','units','metric')
            end
            switch app.DropDown_Units.Value
                case 'Metric'
                    unitsData.SelectedSystem = 'Metric';
                    setpref('cifrib','units','metric');
                case 'Imperial'
                    unitsData.SelectedSystem = 'Imperial';
                    setpref('cifrib','units','imperial');
            end


            % if ~ispref('cifrib','language')
            %     addpref('cifrib','language','english')
            % end
            switch app.DropDown_Language.Value
                case 'English'
                    setpref('cifrib','language','english');
                case 'Deutsch'
                    setpref('cifrib','language','german');
            end


            save('unitsData.mat', 'unitsData');
            uialert(app.UIFigure, ...
                    'Changes take effect after a restart of the programm', ...
                    'Restart required', ...
                    'Icon', 'info');
            %loadUnits(mainApp)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.PreferencesPanel = uipanel(app.GridLayout);
            app.PreferencesPanel.Title = 'Preferences';
            app.GridLayout29 = uigridlayout(app.PreferencesPanel);
            app.TabGroup = uitabgroup(app.GridLayout29);
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.GridLayout28 = uigridlayout(app.GeneralTab);
            app.Panel = uipanel(app.GridLayout28);
            app.GridLayout27 = uigridlayout(app.Panel);
            app.UnitsDropDownLabel = uilabel(app.GridLayout27);
            app.UnitsDropDownLabel.Text = 'Units';
            app.DropDown_Units = uidropdown(app.GridLayout27);
            app.DropDown_Units.Items = {'Metric', 'Imperial'};
            app.DropDown_Units.Value = 'Metric';
            app.LanguageDropDownLabel = uilabel(app.GridLayout27);
            app.LanguageDropDownLabel.Text = 'Language';
            app.DropDown_Language = uidropdown(app.GridLayout27);
            app.DropDown_Language.Items = {'English', 'Deutsch'};
            app.DropDown_Language.Value = 'English';
            app.Checkbox_InitialSetup = uicheckbox(app.GridLayout27);
            app.Checkbox_InitialSetup.Text = 'Hide initial setup at start';
            app.Panel_2 = uipanel(app.GridLayout28);
            app.GridLayout30 = uigridlayout(app.Panel_2);
            app.SaveButton = uibutton(app.GridLayout30, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Preferences(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end